classdef DSP0_MATLAB_q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel2                      matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        MEditField                  matlab.ui.control.NumericEditField
        MEditFieldLabel             matlab.ui.control.Label
        DownsamplingLabel           matlab.ui.control.Label
        wEditField                  matlab.ui.control.NumericEditField
        wEditFieldLabel             matlab.ui.control.Label
        FilterCutofffrequencyLabel  matlab.ui.control.Label
        LEditField                  matlab.ui.control.NumericEditField
        LEditFieldLabel             matlab.ui.control.Label
        UpsamplingLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        Sincn2N2Label               matlab.ui.control.Label
    end

    
    properties (Access = public)
        N 
        M
        L
        Md
        wd
        x1
        w
        n
    end
    methods (Access = public)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.M=300;
            app.NEditField.Value=3;
            app.N=app.NEditField.Value;
            app.LEditField.Value=1;
            app.L=app.LEditField.Value;
            app.MEditField.Value=1;
            app.Md=app.MEditField.Value;
            app.wEditField.Value=0;
            app.wd=app.wEditField.Value;
            app.w=linspace(-3*pi,3*pi,app.M);
            app.n=0:1:app.M-1;
            app.x1= sinc((app.n-app.M/2)/(2*app.N)).^2;
            app.x1=downsample(app.x1,app.Md);  

            app.x1=upsample(app.x1,app.L);
            [h,~]=freqz(app.x1,1,app.w);

            plot(app.UIAxes,app.w/pi,abs(h))
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            position = app.Panel.Position;
            app.N=app.NEditField.Value;
            app.x1=sinc((app.n-app.M/2)/(2*app.N)).^2;
            app.x1=upsample(app.x1,app.L);
            app.x1=downsample(app.x1,app.Md);

            [h,~]=freqz(app.x1,1,app.w);
            
            plot(app.UIAxes,app.w/pi,abs(h))
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            app.L=app.LEditField.Value;
            app.x1=upsample(app.x1,app.L);

            [h,~]=freqz(app.x1,1,app.w);

            plot(app.UIAxes,app.w/pi,abs(h))
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.Md=app.MEditField.Value;
            app.x1=downsample(app.x1,app.Md);
            [h,~]=freqz(app.x1,1,app.w);

            plot(app.UIAxes,app.w/pi,abs(h))
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            value = app.wEditField.Value;
            app.wd=app.wEditField.Value;
            xn=freqz(app.x1,1,app.w);
            if(app.wd>0)
                b = fir1(40, app.wd);
                x=conv(app.x1,b);
                [h,~]=freqz(b,1,app.w);
                x2=h.*xn;
                plot(app.UIAxes,app.w/pi,abs(x2))
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Sincn2N2Label = uilabel(app.Panel);
            app.Sincn2N2Label.Text = 'Sinc(n/2N)^2';
            app.NEditFieldLabel = uilabel(app.Panel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Panel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.UpsamplingLabel = uilabel(app.Panel);
            app.UpsamplingLabel.Text = 'Upsampling';
            app.LEditFieldLabel = uilabel(app.Panel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Panel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.FilterCutofffrequencyLabel = uilabel(app.Panel);
            app.FilterCutofffrequencyLabel.Text = 'Filter Cut-off frequency';
            app.wEditFieldLabel = uilabel(app.Panel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.Panel, 'numeric');
            app.wEditField.Limits = [0 1];
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.DownsamplingLabel = uilabel(app.Panel);
            app.DownsamplingLabel.Text = 'Downsampling';
            app.MEditFieldLabel = uilabel(app.Panel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.Panel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'Frequency Response')
            xlabel(app.UIAxes, 'Normalized Frequency')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP0_MATLAB_q2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end